classdef TeamProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SoundPanel                    matlab.ui.container.Panel
        Sound9Button                  matlab.ui.control.Button
        Sound8Button                  matlab.ui.control.Button
        Sound7Button                  matlab.ui.control.Button
        Sound6Button                  matlab.ui.control.Button
        Sound5Button                  matlab.ui.control.Button
        Sound4Button                  matlab.ui.control.Button
        Sound3Button                  matlab.ui.control.Button
        Sound2Button                  matlab.ui.control.Button
        Sound1Button                  matlab.ui.control.Button
        MessageTextArea               matlab.ui.control.TextArea
        MessageTextAreaLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicModeTab                  matlab.ui.container.Tab
        SynthesizerPanel              matlab.ui.container.Panel
        LaButton                      matlab.ui.control.StateButton
        SolButton                     matlab.ui.control.StateButton
        FaButton                      matlab.ui.control.StateButton
        MiButton                      matlab.ui.control.StateButton
        ReButton                      matlab.ui.control.StateButton
        DoButton                      matlab.ui.control.StateButton
        SawWaveCheckBox               matlab.ui.control.CheckBox
        PulseWaveCheckBox             matlab.ui.control.CheckBox
        SineWaveCheckBox              matlab.ui.control.CheckBox
        PlayButton_2                  matlab.ui.control.StateButton
        SavingPlaceDropDownLabel      matlab.ui.control.Label
        SavingPlaceDropDown           matlab.ui.control.DropDown
        SaveButton_2                  matlab.ui.control.Button
        ChoppingPanel                 matlab.ui.container.Panel
        SavingPlaceDropDown_2Label    matlab.ui.control.Label
        SavingPlaceDropDown_2         matlab.ui.control.DropDown
        PlayButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        SoundNoDropDown_2             matlab.ui.control.DropDown
        SoundNoDropDown_2Label        matlab.ui.control.Label
        SoundSlider                   matlab.ui.control.RangeSlider
        SoundSliderLabel              matlab.ui.control.Label
        EqualizerPanel                matlab.ui.container.Panel
        SoundNoDropDown_5             matlab.ui.control.DropDown
        SoundNoDropDown_5Label        matlab.ui.control.Label
        PlayCheckBox_2                matlab.ui.control.CheckBox
        HzSlider_7                    matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        KHzSlider_2                   matlab.ui.control.Slider
        KHzSlider_2Label              matlab.ui.control.Label
        KHzSlider                     matlab.ui.control.Slider
        KHzSliderLabel                matlab.ui.control.Label
        HzSlider_4                    matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        HzSlider_3                    matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        HzSlider_2                    matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        EffectandSampleModificationPanel  matlab.ui.container.Panel
        EchoKnob                      matlab.ui.control.Knob
        EchoKnobLabel                 matlab.ui.control.Label
        PlaybackSpeedSlider           matlab.ui.control.Slider
        PlaybackSpeedSliderLabel      matlab.ui.control.Label
        PlaybackDirectionSwitch       matlab.ui.control.Switch
        PlaybackDirectionSwitchLabel  matlab.ui.control.Label
        PlaySwitch                    matlab.ui.control.ToggleSwitch
        PlaySwitchLabel               matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        SoundNoDropDown               matlab.ui.control.DropDown
        SoundNoDropDownLabel          matlab.ui.control.Label
        LunchPadTab                   matlab.ui.container.Tab
        StartPointSlider              matlab.ui.control.Slider
        StartPointSliderLabel         matlab.ui.control.Label
        PlayButton_3                  matlab.ui.control.Button
        SavingPlaceDropDown_3Label    matlab.ui.control.Label
        SavingPlaceDropDown_3         matlab.ui.control.DropDown
        SaveButton_3                  matlab.ui.control.Button
        SoundNoDropDown_4Label        matlab.ui.control.Label
        SoundNoDropDown_4             matlab.ui.control.DropDown
        DrumButton                    matlab.ui.control.Button
        BassButton                    matlab.ui.control.Button
        MelodyButton                  matlab.ui.control.Button
        MelodyButton_5                matlab.ui.control.Button
        DrumButton_2                  matlab.ui.control.Button
        BassButton_2                  matlab.ui.control.Button
        MelodyButton_2                matlab.ui.control.Button
        MelodyButton_6                matlab.ui.control.Button
        DrumButton_3                  matlab.ui.control.Button
        BassButton_3                  matlab.ui.control.Button
        MelodyButton_7                matlab.ui.control.Button
        DrumButton_4                  matlab.ui.control.Button
        BassButton_4                  matlab.ui.control.Button
        MelodyButton_4                matlab.ui.control.Button
        MelodyButton_8                matlab.ui.control.Button
        MelodyButton_3                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audio = struct('yValue', {0, 0, 0, 0, 0, 0, 0, 0, 0}, 'frequency', {0, 0, 0, 0, 0, 0, 0, 0, 0}); 

        %synthesizer 
        hertz = 440.00 % default value is 440Hz (A sound)  
        synthesizeAudio
        sampleRate
        synPlayer 
        syntime
        synSaveNum
        currentWave
        newValue 

    end
    
    methods (Access = private)
        
        function [yvalue, Fs] = soundInput(app)
            [filename,filepath] =uigetfile({'*.wav; *.mp3'}, 'Select File to Open');
            fullname = [filepath, filename];
            [firstValue, secondValue] = audioread(fullname);
            yvalue = firstValue;
            Fs = secondValue; 
        end
       
        
        function updateWaveform(app, src)
            if isplaying(src)
                currentPosition = get(src, 'CurrentSample');  
                startPosition = max(1, currentPosition - round(0.05 * app.sampleRate)); 
                audioSegment = app.synthesizeAudio(startPosition:currentPosition); 
                timeAxe2 = (0 : length(audioSegment)-1) / app.sampleRate;

                plot(app.UIAxes, timeAxe2, audioSegment); 
                
            end
        end
        
        function synthesizerFrequencyChanged(app)
            currentAudio = app.synthesizeAudio; 

            switch app.currentWave
                case 100 % sine wave
                    newAudio = sin(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    synthesizeAudioFile = 'sine_wave.wav'; 
                    audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
                case 101 % pulse wave
                    newAudio = square(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    synthesizeAudioFile = 'pulse_wave.wav'; 
                    audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
                case 110 % sawtooth wave 
                    newAudio = sawtooth(2 * pi * app.hertz * app.syntime)';
                    app.synthesizeAudio = [currentAudio; newAudio];
                    synthesizeAudioFile = 'sawtooth_wave.wav'; 
                    audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
            end
            
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate);
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Sound1Button
        function Sound1ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(1).yValue = y; 
            app.audio(1).frequency = Fs; 
            app.Sound1Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound2Button
        function Sound2ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(2).yValue = y; 
            app.audio(2).frequency = Fs; 
            app.Sound2Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound3Button
        function Sound3ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(3).yValue = y; 
            app.audio(3).frequency = Fs; 
            app.Sound3Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound4Button
        function Sound4ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(4).yValue = y; 
            app.audio(4).frequency = Fs; 
            app.Sound4Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound5Button
        function Sound5ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(5).yValue = y; 
            app.audio(5).frequency = Fs; 
            app.Sound5Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound6Button
        function Sound6ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(6).yValue = y; 
            app.audio(6).frequency = Fs; 
            app.Sound6Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound7Button
        function Sound7ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(7).yValue = y; 
            app.audio(7).frequency = Fs; 
            app.Sound7Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound8Button
        function Sound8ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(8).yValue = y; 
            app.audio(8).frequency = Fs; 
            app.Sound8Button.FontColor = [0, 0.8, 0];
        end

        % Button pushed function: Sound9Button
        function Sound9ButtonPushed(app, event)
            [y, Fs]= app.soundInput(); 
            app.audio(9).yValue = y; 
            app.audio(9).frequency = Fs; 
            app.Sound9Button.FontColor = [0, 0.8, 0];
        end

        % Value changed function: SineWaveCheckBox
        function SineWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks  
            app.PulseWaveCheckBox.Value = 0; 
            app.SawWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 100; 
                
            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate);  % generating time vector
            app.synthesizeAudio = sin(2 * pi * app.hertz * app.syntime)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); 
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);

            % Saving audio 
            synthesizeAudioFile = 'sine_wave.wav'; 
            audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: PulseWaveCheckBox
        function PulseWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks  
            app.SineWaveCheckBox.Value = 0; 
            app.SawWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 101; 
            
            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate); % generating time vector
            app.synthesizeAudio = square(2 * pi * app.hertz * app.syntime, 50)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); 
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src);
            

            % Saving audio 
            synthesizeAudioFile = 'pulse_wave.wav'; 
            audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: SawWaveCheckBox
        function SawWaveCheckBoxValueChanged(app, event)
            % Reset other checkbox to avoid duplicate checks 
            app.SineWaveCheckBox.Value = 0; 
            app.PulseWaveCheckBox.Value = 0; 

            % for synthesizerFrequencyChanged function, saving current wave
            app.currentWave = 110;

            % making sinewave 
            duration = 1; % playing time (10 second) 
            app.sampleRate = 44100; % sampling speed (Hz) 
            app.syntime = linspace(0, duration, duration * app.sampleRate);  % generating time vector
            app.synthesizeAudio = sawtooth(2 * pi * app.hertz * app.syntime)'; % Generating sine wave with app.hertz
            app.synPlayer = audioplayer(app.synthesizeAudio, app.sampleRate); % making audioplayer to play audio
            app.synPlayer.TimerFcn = @(src, event) app.updateWaveform(src); % function to show the audio wave 
            

            % Saving audio 
            synthesizeAudioFile = 'sawtooth_wave.wav'; 
            audiowrite(synthesizeAudioFile, app.synthesizeAudio, app.sampleRate);
        end

        % Value changed function: PlayButton_2
        function PlayButton_2ValueChanged(app, event)
            value = app.PlayButton_2.Value;
             
            if value == 1 % play the synthesized audio
               play(app.synPlayer); 
               app.PlayButton_2.Value = 0; %reset play button 
            else % stop the synthesized audio
               stop(app.synPlayer); 
            end
            
        end

        % Value changed function: SavingPlaceDropDown
        function SavingPlaceDropDownValueChanged(app, event)
            value = app.SavingPlaceDropDown.Value;

            % selecting sound Button num to save the synthesized audio
            switch value 
                case "Sound 1"
                    app.synSaveNum = 1; 
                case "Sound 2"
                    app.synSaveNum = 2; 
                case "Sound 3"
                    app.synSaveNum = 3; 
                case "Sound 4"
                    app.synSaveNum = 4; 
                case "Sound 5"
                    app.synSaveNum = 5; 
                case "Sound 6"
                    app.synSaveNum = 6; 
                case "Sound 7"
                    app.synSaveNum = 7; 
                case "Sound 8"
                    app.synSaveNum = 8; 
                case "Sound 9"
                    app.synSaveNum = 9;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            if isscalar(app.synthesizeAudio)  % error detector(if there is no synthesized audio)  

            else
                % saving y value and frequency in sound button  
                app.audio(app.synSaveNum).yValue = app.synthesizeAudio; 
                app.audio(app.synSaveNum).frequency = app.sampleRate;
                
                % changing sound button color 
                switch app.synSaveNum 
                    case 1
                        app.Sound1Button.FontColor = [1, 0.5, 0]; 
                    case 2
                        app.Sound2Button.FontColor = [1, 0.5, 0];
                    case 3
                        app.Sound3Button.FontColor = [1, 0.5, 0];
                    case 4
                        app.Sound4Button.FontColor = [1, 0.5, 0]; 
                    case 5
                        app.Sound5Button.FontColor = [1, 0.5, 0]; 
                    case 6
                        app.Sound6Button.FontColor = [1, 0.5, 0]; 
                    case 7
                        app.Sound7Button.FontColor = [1, 0.5, 0];
                    case 8
                        app.Sound8Button.FontColor = [1, 0.5, 0]; 
                    case 9
                        app.Sound9Button.FontColor = [1, 0.5, 0];
                end
            end

            
        end

        % Value changed function: DoButton
        function DoButtonValueChanged(app, event)
            % reset other value
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;

            app.hertz = 261.63; % C sound 
            
            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 

            
        end

        % Value changed function: ReButton
        function ReButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz = 293.66; % D sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: MiButton
        function MiButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.FaButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  329.63; % E sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: FaButton
        function FaButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.SolButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  349.23; % F sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: SolButton
        function SolButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0;
            app.LaButton.Value = 0;
            
            app.hertz =  392.00; % G sound 

            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end

        % Value changed function: LaButton
        function LaButtonValueChanged(app, event)
            % reset other value
            app.DoButton.Value = 0; 
            app.ReButton.Value = 0; 
            app.MiButton.Value = 0; 
            app.FaButton.Value = 0;
            app.SolButton.Value = 0;
            
            app.hertz =  440.00; % A sound (Default sound)  
    
            % Change the audio's frequency 
            app.synthesizerFrequencyChanged(); 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1.0'; '1.2'; '1.4'; '1.6'; '1.8'; '2'};
            app.UIAxes.YTickLabel = {'0'; '0.4'; '0.8'; '1.2'; '1.6'; '2'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicModeTab = uitab(app.TabGroup);
            app.BasicModeTab.Title = 'Basic Mode';
            app.EffectandSampleModificationPanel = uipanel(app.BasicModeTab);
            app.EffectandSampleModificationPanel.Title = 'Effect and Sample Modification ';
            app.SoundNoDropDownLabel = uilabel(app.EffectandSampleModificationPanel);
            app.SoundNoDropDownLabel.Text = 'Sound No.';
            app.SoundNoDropDown = uidropdown(app.EffectandSampleModificationPanel);
            app.SoundNoDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown.Value = 'Sound 1';
            app.VolumeKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.PlaySwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.EffectandSampleModificationPanel, 'toggle');
            app.PlaybackDirectionSwitchLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackDirectionSwitchLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectandSampleModificationPanel, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Forward', 'Backward'};
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectandSampleModificationPanel);
            app.PlaybackSpeedSlider.Limits = [0.5 2];
            app.PlaybackSpeedSlider.Value = 0.5;
            app.EchoKnobLabel = uilabel(app.EffectandSampleModificationPanel);
            app.EchoKnobLabel.Text = 'Echo';
            app.EchoKnob = uiknob(app.EffectandSampleModificationPanel, 'continuous');
            app.EqualizerPanel = uipanel(app.BasicModeTab);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HzLabel = uilabel(app.EqualizerPanel);
            app.HzLabel.Text = '170Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzLabel_2 = uilabel(app.EqualizerPanel);
            app.HzLabel_2.Text = '310Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzLabel_4 = uilabel(app.EqualizerPanel);
            app.HzLabel_4.Text = '600Hz';
            app.HzSlider_4 = uislider(app.EqualizerPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '1KHz';
            app.KHzSlider = uislider(app.EqualizerPanel);
            app.KHzSlider.Limits = [-3 3];
            app.KHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.KHzSlider_2Label.Text = '3KHz';
            app.KHzSlider_2 = uislider(app.EqualizerPanel);
            app.KHzSlider_2.Limits = [-3 3];
            app.HzLabel_3 = uilabel(app.EqualizerPanel);
            app.HzLabel_3.Text = '60Hz';
            app.HzSlider_7 = uislider(app.EqualizerPanel);
            app.HzSlider_7.Limits = [-3 3];
            app.PlayCheckBox_2 = uicheckbox(app.EqualizerPanel);
            app.PlayCheckBox_2.Text = 'Play';
            app.SoundNoDropDown_5Label = uilabel(app.EqualizerPanel);
            app.SoundNoDropDown_5Label.Text = 'Sound No.';
            app.SoundNoDropDown_5 = uidropdown(app.EqualizerPanel);
            app.SoundNoDropDown_5.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_5.Value = 'Sound 1';
            app.ChoppingPanel = uipanel(app.BasicModeTab);
            app.ChoppingPanel.Title = 'Chopping';
            app.SoundSliderLabel = uilabel(app.ChoppingPanel);
            app.SoundSliderLabel.Text = 'Sound';
            app.SoundSlider = uislider(app.ChoppingPanel, 'range');
            app.SoundNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SoundNoDropDown_2Label.Text = 'Sound No.';
            app.SoundNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SoundNoDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_2.Value = 'Sound 1';
            app.SaveButton = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.ChoppingPanel, 'state');
            app.PlayButton.Text = 'Play';
            app.SavingPlaceDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SavingPlaceDropDown_2.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_2.Value = 'Sound 1';
            app.SavingPlaceDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SavingPlaceDropDown_2Label.Text = 'Saving Place';
            app.SynthesizerPanel = uipanel(app.BasicModeTab);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.SaveButton_2 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SavingPlaceDropDown = uidropdown(app.SynthesizerPanel);
            app.SavingPlaceDropDown.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SavingPlaceDropDownValueChanged, true);
            app.SavingPlaceDropDown.Value = 'Sound 1';
            app.SavingPlaceDropDownLabel = uilabel(app.SynthesizerPanel);
            app.SavingPlaceDropDownLabel.Text = 'Saving Place';
            app.PlayButton_2 = uibutton(app.SynthesizerPanel, 'state');
            app.PlayButton_2.ValueChangedFcn = createCallbackFcn(app, @PlayButton_2ValueChanged, true);
            app.PlayButton_2.Text = 'Play';
            app.SineWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SineWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SineWaveCheckBoxValueChanged, true);
            app.SineWaveCheckBox.Text = 'Sine Wave';
            app.PulseWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.PulseWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseWaveCheckBoxValueChanged, true);
            app.PulseWaveCheckBox.Text = 'Pulse Wave';
            app.SawWaveCheckBox = uicheckbox(app.SynthesizerPanel);
            app.SawWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SawWaveCheckBoxValueChanged, true);
            app.SawWaveCheckBox.Text = 'Saw Wave';
            app.DoButton = uibutton(app.SynthesizerPanel, 'state');
            app.DoButton.ValueChangedFcn = createCallbackFcn(app, @DoButtonValueChanged, true);
            app.DoButton.Text = 'Do';
            app.ReButton = uibutton(app.SynthesizerPanel, 'state');
            app.ReButton.ValueChangedFcn = createCallbackFcn(app, @ReButtonValueChanged, true);
            app.ReButton.Text = 'Re';
            app.MiButton = uibutton(app.SynthesizerPanel, 'state');
            app.MiButton.ValueChangedFcn = createCallbackFcn(app, @MiButtonValueChanged, true);
            app.MiButton.Text = 'Mi';
            app.FaButton = uibutton(app.SynthesizerPanel, 'state');
            app.FaButton.ValueChangedFcn = createCallbackFcn(app, @FaButtonValueChanged, true);
            app.FaButton.Text = 'Fa';
            app.SolButton = uibutton(app.SynthesizerPanel, 'state');
            app.SolButton.ValueChangedFcn = createCallbackFcn(app, @SolButtonValueChanged, true);
            app.SolButton.Text = 'Sol';
            app.LaButton = uibutton(app.SynthesizerPanel, 'state');
            app.LaButton.ValueChangedFcn = createCallbackFcn(app, @LaButtonValueChanged, true);
            app.LaButton.Text = 'La';
            app.LunchPadTab = uitab(app.TabGroup);
            app.LunchPadTab.Title = 'LunchPad';
            app.MelodyButton_3 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_3.Text = 'Melody';
            app.MelodyButton_8 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_8.Text = 'Melody';
            app.MelodyButton_4 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_4.Text = 'Melody';
            app.BassButton_4 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_4.Text = 'Bass';
            app.DrumButton_4 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_4.Text = 'Drum';
            app.MelodyButton_7 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_7.Text = 'Melody';
            app.BassButton_3 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_3.Text = 'Bass';
            app.DrumButton_3 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_3.Text = 'Drum';
            app.MelodyButton_6 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_6.Text = 'Melody';
            app.MelodyButton_2 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_2.Text = 'Melody';
            app.BassButton_2 = uibutton(app.LunchPadTab, 'push');
            app.BassButton_2.Text = 'Bass';
            app.DrumButton_2 = uibutton(app.LunchPadTab, 'push');
            app.DrumButton_2.Text = 'Drum';
            app.MelodyButton_5 = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton_5.Text = 'Melody';
            app.MelodyButton = uibutton(app.LunchPadTab, 'push');
            app.MelodyButton.Text = 'Melody';
            app.BassButton = uibutton(app.LunchPadTab, 'push');
            app.BassButton.Text = 'Bass';
            app.DrumButton = uibutton(app.LunchPadTab, 'push');
            app.DrumButton.Text = 'Drum';
            app.SoundNoDropDown_4 = uidropdown(app.LunchPadTab);
            app.SoundNoDropDown_4.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SoundNoDropDown_4.Value = 'Sound 1';
            app.SoundNoDropDown_4Label = uilabel(app.LunchPadTab);
            app.SoundNoDropDown_4Label.Text = 'Sound No.';
            app.SaveButton_3 = uibutton(app.LunchPadTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.SavingPlaceDropDown_3 = uidropdown(app.LunchPadTab);
            app.SavingPlaceDropDown_3.Items = {'Sound 1', 'Sound 2', 'Sound 3', 'Sound 4 ', 'Sound 5', 'Sound 6', 'Sound 7', 'Sound 8', 'Sound 9'};
            app.SavingPlaceDropDown_3.Value = 'Sound 1';
            app.SavingPlaceDropDown_3Label = uilabel(app.LunchPadTab);
            app.SavingPlaceDropDown_3Label.Text = 'Saving Place';
            app.PlayButton_3 = uibutton(app.LunchPadTab, 'push');
            app.PlayButton_3.Text = 'Play';
            app.StartPointSliderLabel = uilabel(app.LunchPadTab);
            app.StartPointSliderLabel.Text = 'Start Point';
            app.StartPointSlider = uislider(app.LunchPadTab);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MessageTextArea.Value = {' '};
            app.SoundPanel = uipanel(app.UIFigure);
            app.SoundPanel.Title = 'Sound';
            app.Sound1Button = uibutton(app.SoundPanel, 'push');
            app.Sound1Button.ButtonPushedFcn = createCallbackFcn(app, @Sound1ButtonPushed, true);
            app.Sound1Button.Text = 'Sound1';
            app.Sound2Button = uibutton(app.SoundPanel, 'push');
            app.Sound2Button.ButtonPushedFcn = createCallbackFcn(app, @Sound2ButtonPushed, true);
            app.Sound2Button.Text = 'Sound2';
            app.Sound3Button = uibutton(app.SoundPanel, 'push');
            app.Sound3Button.ButtonPushedFcn = createCallbackFcn(app, @Sound3ButtonPushed, true);
            app.Sound3Button.Text = 'Sound3';
            app.Sound4Button = uibutton(app.SoundPanel, 'push');
            app.Sound4Button.ButtonPushedFcn = createCallbackFcn(app, @Sound4ButtonPushed, true);
            app.Sound4Button.Text = 'Sound4';
            app.Sound5Button = uibutton(app.SoundPanel, 'push');
            app.Sound5Button.ButtonPushedFcn = createCallbackFcn(app, @Sound5ButtonPushed, true);
            app.Sound5Button.Text = 'Sound5';
            app.Sound6Button = uibutton(app.SoundPanel, 'push');
            app.Sound6Button.ButtonPushedFcn = createCallbackFcn(app, @Sound6ButtonPushed, true);
            app.Sound6Button.Text = 'Sound6';
            app.Sound7Button = uibutton(app.SoundPanel, 'push');
            app.Sound7Button.ButtonPushedFcn = createCallbackFcn(app, @Sound7ButtonPushed, true);
            app.Sound7Button.Text = 'Sound7';
            app.Sound8Button = uibutton(app.SoundPanel, 'push');
            app.Sound8Button.ButtonPushedFcn = createCallbackFcn(app, @Sound8ButtonPushed, true);
            app.Sound8Button.Text = 'Sound8';
            app.Sound9Button = uibutton(app.SoundPanel, 'push');
            app.Sound9Button.ButtonPushedFcn = createCallbackFcn(app, @Sound9ButtonPushed, true);
            app.Sound9Button.Text = 'Sound9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeamProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end